classdef Remove_Periodic_Frequencies < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RemovePeriodicFrequenciesUIFigure  matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        LeftPanel               matlab.ui.container.Panel
        Og_Images_Axis          matlab.ui.control.UIAxes
        CenterPanel             matlab.ui.container.Panel
        ClickonMaskNewRegiontozerooutspecificfrequenciesLabel  matlab.ui.control.Label
        MaskNewRegionButton     matlab.ui.control.Button
        FFT_Image_Axis          matlab.ui.control.UIAxes
        RightPanel              matlab.ui.container.Panel
        ClickCheckResultstoseethefilteredimageLabel  matlab.ui.control.Label
        ClickTryAgaintostartmaskingoverwiththesamenoiseLabel  matlab.ui.control.Label
        ClickNewNoisetogenerateanewperiodicnoiseLabel  matlab.ui.control.Label
        NewNoiseButton          matlab.ui.control.Button
        CheckResultsButton      matlab.ui.control.Button
        TryAgainButton          matlab.ui.control.Button
        Masked_iFFT_Image_Axis  matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = public)
        OG_Clean_Image
        OG_Noisy_Image
        OG_FFT
        M_i_Image
        Freq_Image
        FFT_Mask
        Current_Results

        Noise_Period
    end
    
    methods (Access = public)
        
        function noised_image = Add_Periodic_Noise(app,clean_image)
            [rows columns] = size(clean_image);
            
            % Setting some properties of the periodic noise
            period = app.Noise_Period;
            amplitude = 0.5;
            offset = 1-amplitude;
            
            rowVector = (1:rows)';
            % Creating the periodic ripples in the image with a cosine
            % function
            cosVector = amplitude * (1+cos(2*pi*rowVector/period))/2 + offset;
            ripplesImage = repmat(cosVector, [1,columns]);

            noised_image = ripplesImage.*clean_image;
            
        end
        
        function results = Apply_Mask_iFFT(app)

            intermediate_image = app.Freq_Image.*app.FFT_Mask;
            filtered_image = ifft2(fftshift(intermediate_image));

            results = abs(filtered_image);
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        % Borrowing from the tutorial provided by: Image Analyst
        % from: https://www.mathworks.com/matlabcentral/answers/471074-remove-periodic-noise-pattern-from-image
        
        % Read in a standard MATLAB gray scale demo image.
        folder = fileparts(which('cameraman.tif')); % Determine where demo folder is (works with all versions).
        baseFileName = 'cameraman.tif';
        % Get the full filename, with path prepended.
        fullFileName = fullfile(folder, baseFileName);

        % Reading in the non-noisy original grayscale image
        og_clean_image = imread(fullFileName);
        [h,w,c] = size(og_clean_image);
        if c>1
            og_clean_image = rgb2gray(og_clean_image);
        end

        app.OG_Clean_Image = double(og_clean_image);

        % Initializing noise period
        app.Noise_Period = 10;

        % Getting the noisy image
        app.OG_Noisy_Image = Add_Periodic_Noise(app,app.OG_Clean_Image);
        imshowpair(app.OG_Clean_Image,app.OG_Noisy_Image,'montage','Parent',app.Og_Images_Axis)
        title(app.Og_Images_Axis,strcat('Original Clean and Noisy Image, Noise Period = ',num2str(app.Noise_Period)))

        % Generating the noisy image in FFT space
        freq_image = fftshift(fft2(app.OG_Noisy_Image));
        app.Freq_Image = freq_image;
        imshow(log(abs(app.Freq_Image)),[],'Parent',app.FFT_Image_Axis)
        title(app.FFT_Image_Axis,'FFT of Noisy Image')

        % Initializing mask (all ones)
        app.FFT_Mask = ones(size(app.OG_Clean_Image));

        % Filtering current noisy image and displaying results
        app.Current_Results = Apply_Mask_iFFT(app);
        imshow(app.Current_Results,[],'Parent',app.Masked_iFFT_Image_Axis)
        title(app.Masked_iFFT_Image_Axis,'Results of Masking')

        end

        % Button pushed function: NewNoiseButton
        function NewNoiseButtonPushed(app, event)
            
            % Getting a new noise period
            app.FFT_Mask = ones(size(app.OG_Clean_Image));

            app.Noise_Period = randi(15,1);
            % Getting the noisy image
            app.OG_Noisy_Image = Add_Periodic_Noise(app,app.OG_Clean_Image);
            imshowpair(app.OG_Clean_Image,app.OG_Noisy_Image,'montage','Parent',app.Og_Images_Axis)
            title(app.Og_Images_Axis,strcat('Original Clean and Noisy Image, Noise Period = ',num2str(app.Noise_Period)))
    
            % Generating the noisy image in FFT space
            freq_image = fftshift(fft2(app.OG_Noisy_Image));
            app.Freq_Image = freq_image;
            imshow(log(abs(app.Freq_Image)),[],'Parent',app.FFT_Image_Axis)
            title(app.FFT_Image_Axis,'FFT of Noisy Image')
    
    
            % Filtering current noisy image and displaying results
            app.Current_Results = Apply_Mask_iFFT(app);
            imshow(app.Current_Results,[],'Parent',app.Masked_iFFT_Image_Axis)
            title(app.Masked_iFFT_Image_Axis,'Results of Masking')

        end

        % Button pushed function: CheckResultsButton
        function CheckResultsButtonPushed(app, event)
            
            cleaned_image = Apply_Mask_iFFT(app);
            app.Current_Results = cleaned_image;

            imshow(app.Current_Results,[],'Parent',app.Masked_iFFT_Image_Axis)
            title(app.Masked_iFFT_Image_Axis,'Results of Masking')

        end

        % Button pushed function: TryAgainButton
        function TryAgainButtonPushed(app, event)
            
            % Clearing the mask and viewing current results
            % Initializing mask (all ones)
            app.FFT_Mask = ones(size(app.OG_Clean_Image));

            % Generating the noisy image in FFT space
            freq_image = fftshift(fft2(app.OG_Noisy_Image));
            app.Freq_Image = freq_image;
            imshow(log(abs(app.Freq_Image)),[],'Parent',app.FFT_Image_Axis)
            title(app.FFT_Image_Axis,'FFT of Noisy Image')
    
    
            % Filtering current noisy image and displaying results
            app.Current_Results = Apply_Mask_iFFT(app);
            imshow(app.Current_Results,[],'Parent',app.Masked_iFFT_Image_Axis)
            title(app.Masked_iFFT_Image_Axis,'Results of Masking')

        end

        % Button pushed function: MaskNewRegionButton
        function MaskNewRegionButtonPushed(app, event)
            
            % Creating new ROI and starting the drawing
            app.MaskNewRegionButton.Enable = 'off';
            app.CheckResultsButton.Enable = 'off';
            app.NewNoiseButton.Enable = 'off';
            app.TryAgainButton.Enable = 'off';

            new_roi = images.roi.Point(app.FFT_Image_Axis,'Color','k');
            new_roi.draw

            point_mask = zeros(size(app.OG_Clean_Image));
            try
                point_mask(ceil(new_roi.Position(2)),ceil(new_roi.Position(1))) = 1;
            end
            app.FFT_Mask = app.FFT_Mask & ~imdilate(point_mask,strel('disk',5));

            app.MaskNewRegionButton.Enable = 'on';
            app.CheckResultsButton.Enable = 'on';
            app.NewNoiseButton.Enable = 'on';
            app.TryAgainButton.Enable = 'on';

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.RemovePeriodicFrequenciesUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {358, '1x', 353};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RemovePeriodicFrequenciesUIFigure = uifigure('Visible', 'off');
            app.RemovePeriodicFrequenciesUIFigure.Name = 'Remove Periodic Frequencies';
            app.RemovePeriodicFrequenciesUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.RemovePeriodicFrequenciesUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Og_Images_Axis = uiaxes(app.LeftPanel);
            app.Og_Images_Axis.XTick = [];
            app.Og_Images_Axis.XTickLabel = '';
            app.Og_Images_Axis.YTick = [];
            app.CenterPanel = uipanel(app.GridLayout);
            app.FFT_Image_Axis = uiaxes(app.CenterPanel);
            app.FFT_Image_Axis.XTickLabel = '';
            app.FFT_Image_Axis.YTickLabel = '';
            app.MaskNewRegionButton = uibutton(app.CenterPanel, 'push');
            app.MaskNewRegionButton.ButtonPushedFcn = createCallbackFcn(app, @MaskNewRegionButtonPushed, true);
            app.MaskNewRegionButton.Text = 'Mask New Region';
            app.ClickonMaskNewRegiontozerooutspecificfrequenciesLabel = uilabel(app.CenterPanel);
            app.ClickonMaskNewRegiontozerooutspecificfrequenciesLabel.Text = 'Click on "Mask New Region" to zero out specific frequencies';
            app.RightPanel = uipanel(app.GridLayout);
            app.Masked_iFFT_Image_Axis = uiaxes(app.RightPanel);
            app.Masked_iFFT_Image_Axis.XTickLabel = '';
            app.Masked_iFFT_Image_Axis.YTickLabel = '';
            app.TryAgainButton = uibutton(app.RightPanel, 'push');
            app.TryAgainButton.ButtonPushedFcn = createCallbackFcn(app, @TryAgainButtonPushed, true);
            app.TryAgainButton.Text = 'Try Again';
            app.CheckResultsButton = uibutton(app.RightPanel, 'push');
            app.CheckResultsButton.ButtonPushedFcn = createCallbackFcn(app, @CheckResultsButtonPushed, true);
            app.CheckResultsButton.Text = 'Check Results!';
            app.NewNoiseButton = uibutton(app.RightPanel, 'push');
            app.NewNoiseButton.ButtonPushedFcn = createCallbackFcn(app, @NewNoiseButtonPushed, true);
            app.NewNoiseButton.Text = 'New Noise';
            app.ClickNewNoisetogenerateanewperiodicnoiseLabel = uilabel(app.RightPanel);
            app.ClickNewNoisetogenerateanewperiodicnoiseLabel.Text = 'Click "New Noise" to generate a new periodic noise';
            app.ClickTryAgaintostartmaskingoverwiththesamenoiseLabel = uilabel(app.RightPanel);
            app.ClickTryAgaintostartmaskingoverwiththesamenoiseLabel.Text = 'Click "Try Again" to start masking over with the same noise';
            app.ClickCheckResultstoseethefilteredimageLabel = uilabel(app.RightPanel);
            app.ClickCheckResultstoseethefilteredimageLabel.Text = 'Click "Check Results" to see the filtered image';
            % Show the figure after all components are created
            app.RemovePeriodicFrequenciesUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Remove_Periodic_Frequencies

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RemovePeriodicFrequenciesUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RemovePeriodicFrequenciesUIFigure)
        end
    end
end